classdef EEGsonic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGSonicUIFigure                matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveSettingsMenu                matlab.ui.container.Menu
        QuitEEGsonicMenu                matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        HomePanel                       matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        CheckRepositoryButton           matlab.ui.control.Button
        GeneralTab                      matlab.ui.container.Tab
        GeneralPanel                    matlab.ui.container.Panel
        SelectSavingDirectoryButton     matlab.ui.control.Button
        SavingDirectoryLabel            matlab.ui.control.Label
        EEGdeviceButtonGroup            matlab.ui.container.ButtonGroup
        EGI129Button                    matlab.ui.control.RadioButton
        DSI24Button                     matlab.ui.control.RadioButton
        VisualizationTab                matlab.ui.container.Tab
        VisualizationPanel              matlab.ui.container.Panel
        SonificationTab                 matlab.ui.container.Tab
        SonificationPanel               matlab.ui.container.Panel
        SpectralPowerRatioTab           matlab.ui.container.Tab
        SpectralPowerRatioPanel         matlab.ui.container.Panel
        WindowSizeinsecondsEditFieldLabel  matlab.ui.control.Label
        SPRWindowSizeEditField          matlab.ui.control.NumericEditField
        TimeBandwithProductLabel        matlab.ui.control.Label
        SPRTimeBandwithProductEditField  matlab.ui.control.NumericEditField
        SPRThetaBandwithCheckBox        matlab.ui.control.CheckBox
        SPRAlphaBandwithCheckBox        matlab.ui.control.CheckBox
        SPRBetaBandwithCheckBox         matlab.ui.control.CheckBox
        AveragingSizeinsecondsLabel     matlab.ui.control.Label
        SPRAveragingSizeEditField       matlab.ui.control.NumericEditField
        NumberofTapersLabel             matlab.ui.control.Label
        SPRNumberTapersEditField        matlab.ui.control.NumericEditField
        ThetaBandwithinHzEditFieldLabel  matlab.ui.control.Label
        SPRThetaBandwithEditField       matlab.ui.control.EditField
        AlphaBandwithinHzLabel_2        matlab.ui.control.Label
        SPRAlphaBandwithEditField       matlab.ui.control.EditField
        BetaBandwithinHzLabel_2         matlab.ui.control.Label
        SPRBetaBandwithEditField        matlab.ui.control.EditField
        TopographicDistributionTab      matlab.ui.container.Tab
        TopographicDistributionPanel    matlab.ui.container.Panel
        WindowSizeinsecondsEditFieldLabel_2  matlab.ui.control.Label
        TDWindowSizeEditField           matlab.ui.control.NumericEditField
        FrequencyinHzLabel              matlab.ui.control.Label
        TDFrequencyEditField            matlab.ui.control.NumericEditField
        PhaseAmplitudeCouplingTab       matlab.ui.container.Tab
        PhaseAmplitudeCouplingPanel     matlab.ui.container.Panel
        WindowSizeinsecondsEditFieldLabel_3  matlab.ui.control.Label
        PACWindowSizeEditField          matlab.ui.control.NumericEditField
        FrontalChannelsholdshiftformultiselectionLabel  matlab.ui.control.Label
        PACFrontalChannelsListBox       matlab.ui.control.ListBox
        ParietalChannelsholdshiftformultiselectionLabel  matlab.ui.control.Label
        PACParietalChannelsListBox      matlab.ui.control.ListBox
        ExtraLowFrequencyinHzLabel      matlab.ui.control.Label
        PACExtraLowFrequencyBandwithEditField  matlab.ui.control.EditField
        HigherFrequencyinHzLabel        matlab.ui.control.Label
        PACHighFrequencyBandwithEditField  matlab.ui.control.EditField
        FrontoparietalwPLITab           matlab.ui.container.Tab
        FrontoparietalwPLIPanel         matlab.ui.container.Panel
        WindowSizeinsecondsEditFieldLabel_4  matlab.ui.control.Label
        WPLIWindowSizeEditField         matlab.ui.control.NumericEditField
        MidlineChannelsholdshiftformultiselectionLabel  matlab.ui.control.Label
        WPLIMidlineChannelsListBox      matlab.ui.control.ListBox
        LateralChannelsholdshiftformultiselectionLabel  matlab.ui.control.Label
        WPLILateralChannelsListBox      matlab.ui.control.ListBox
        NumberofSurrogatesLabel         matlab.ui.control.Label
        WPLINumberSurrogatesEditField   matlab.ui.control.NumericEditField
        pValueLabel                     matlab.ui.control.Label
        WPLIPValueEditField             matlab.ui.control.NumericEditField
        FrontoparietaldPLITab           matlab.ui.container.Tab
        FrontoparietaldPLIPanel         matlab.ui.container.Panel
        HubLocationTab                  matlab.ui.container.Tab
        HubLocationPanel                matlab.ui.container.Panel
        PermutationEntropyTab           matlab.ui.container.Tab
        PermutationEntropyPanel         matlab.ui.container.Panel
        EEGSonicPanel                   matlab.ui.container.Panel
        LaunchButton                    matlab.ui.control.Button
        FeedbackTextField               matlab.ui.control.TextArea
        SpectralPowerRatioCheckBox      matlab.ui.control.CheckBox
        TopographicDistributionCheckBox  matlab.ui.control.CheckBox
        PhaseAmplitudeCouplingCheckBox  matlab.ui.control.CheckBox
        FrontoparietalwPLICheckBox      matlab.ui.control.CheckBox
        FrontoparietaldPLICheckBox      matlab.ui.control.CheckBox
        HubLocationCheckBox             matlab.ui.control.CheckBox
        PermutationEntropyCheckBox      matlab.ui.control.CheckBox
        SonificationCheckBox            matlab.ui.control.CheckBox
        VisualizationCheckBox           matlab.ui.control.CheckBox
    end


    properties (Access = private)
        % Strings
        start_message;
        exit_message;
        
        parameters;
        information;
    end

    methods (Access = private)
    
        function save_gui(app)
            % Save checkbox selection
            app.parameters.visualization.is_selected = app.VisualizationCheckBox.Value;
            app.parameters.sonification.is_selected = app.SonificationCheckBox.Value;
            app.parameters.spr.is_selected = app.SpectralPowerRatioCheckBox.Value;
            app.parameters.td.is_selected = app.TopographicDistributionCheckBox.Value;
            app.parameters.pac.is_selected = app.PhaseAmplitudeCouplingCheckBox.Value;
            app.parameters.fp_wpli.is_selected = app.FrontoparietalwPLICheckBox.Value;
            app.parameters.fp_dPLI.is_selected = app.FrontoparietaldPLICheckBox.Value;
            app.parameters.hl.is_selected = app.HubLocationCheckBox.Value;
            app.parameters.pe.is_selected = app.PermutationEntropyCheckBox.Value;
            
            % Save buttons
            app.parameters.general.egi129.is_selected = app.EGI129Button.Value;
            app.parameters.general.dsi24.is_selected = app.DSI24Button.Value;
            
            % Save SPR Panel
            app.parameters.spr.window_size = app.SPRWindowSizeEditField.Value;
            app.parameters.spr.time_bandwith_product = app.SPRTimeBandwithProductEditField.Value;
            theta_bandwith = erase(app.SPRThetaBandwithEditField.Value,'[');
            theta_bandwith = erase(theta_bandwith,']');
            app.parameters.spr.theta.bandwith = str2double(strsplit(theta_bandwith,{',',' '}));
            app.parameters.spr.theta.is_selected = app.SPRThetaBandwithCheckBox.Value;
            alpha_bandwith = erase(app.SPRAlphaBandwithEditField.Value,'[');
            alpha_bandwith = erase(alpha_bandwith,']');
            app.parameters.spr.alpha.bandwith = str2double(strsplit(alpha_bandwith,{',',' '}));
            app.parameters.spr.alpha.is_selected = app.SPRAlphaBandwithCheckBox.Value;
            beta_bandwith = erase(app.SPRBetaBandwithEditField.Value,'[');
            beta_bandwith = erase(beta_bandwith,']');
            app.parameters.spr.beta.bandwith = str2double(strsplit(beta_bandwith,{',',' '}));
            app.parameters.spr.beta.is_selected = app.SPRBetaBandwithCheckBox.Value;
            
            % Save TD Panel
            app.parameters.td.window_size = app.TDWindowSizeEditField.Value;
            app.parameters.td.frequency = app.TDFrequencyEditField.Value;
            
            % Save PAC Panel
            app.parameters.pac.window_size = app.PACWindowSizeEditField.Value;
            %TODO Make a converter from {} array to [0,1,0,...,1]
            if(app.parameters.general.egi129.is_selected)
                app.parameters.pac.frontal_channels.egi129 = app.PACFrontalChannelsListBox.Value;
                app.parameters.pac.parietal_channels.egi129 = app.PACParietalChannelsListBox.Value;
            elseif(app.parameters.general.dsi24.is_selected)
                app.parameters.pac.frontal_channels.dsi24 = app.PACFrontalChannelsListBox.Value;
                app.parameters.pac.parietal_channels.dsi24 = app.PACParietalChannelsListBox.Value;
            end
            
            %TODO Make a function out of this process and reduce this to one line
            extra_low_frequency_bandwith = erase(app.PACExtraLowFrequencyBandwithEditField.Value,'[');
            extra_low_frequency_bandwith = erase(extra_low_frequency_bandwith,']');
            app.parameters.pac.extra_low_frequency.bandwith = str2double(strsplit(extra_low_frequency_bandwith,{',',' '}));
            
            high_frequency_bandwith = erase(app.PACHighFrequencyBandwithEditField.Value,'[');
            high_frequency_bandwith = erase(high_frequency_bandwith,']');
            app.parameters.pac.high_frequency.bandwith = str2double(strsplit(high_frequency_bandwith,{',',' '}));
        end


        function update_gui(app)
            % Update Checkboxes
            app.VisualizationCheckBox.Value = app.parameters.visualization.is_selected;
            app.SonificationCheckBox.Value = app.parameters.sonification.is_selected;
            app.SpectralPowerRatioCheckBox.Value = app.parameters.spr.is_selected;
            app.TopographicDistributionCheckBox.Value = app.parameters.td.is_selected;
            app.PhaseAmplitudeCouplingCheckBox.Value = app.parameters.pac.is_selected;
            app.FrontoparietalwPLICheckBox.Value = app.parameters.fp_wpli.is_selected;
            app.FrontoparietaldPLICheckBox.Value = app.parameters.fp_dPLI.is_selected;
            app.HubLocationCheckBox.Value = app.parameters.hl.is_selected;
            app.PermutationEntropyCheckBox.Value = app.parameters.pe.is_selected;     
            
            % Update Buttons
            app.EGI129Button.Value = app.parameters.general.egi129.is_selected;
            app.DSI24Button.Value = app.parameters.general.dsi24.is_selected;
            
            % Update text fields
            app.SavingDirectoryLabel.Text = app.information.saving_directory;
            
            % Update SPR Panel
            app.SPRWindowSizeEditField.Value = app.parameters.spr.window_size;
            app.SPRTimeBandwithProductEditField.Value = app.parameters.spr.time_bandwith_product;
            app.SPRThetaBandwithEditField.Value = mat2str(app.parameters.spr.theta.bandwith);
            app.SPRThetaBandwithCheckBox.Value = app.parameters.spr.theta.is_selected;
            app.SPRAlphaBandwithEditField.Value = mat2str(app.parameters.spr.alpha.bandwith);
            app.SPRAlphaBandwithCheckBox.Value = app.parameters.spr.alpha.is_selected;
            app.SPRBetaBandwithEditField.Value = mat2str(app.parameters.spr.beta.bandwith);
            app.SPRBetaBandwithCheckBox.Value = app.parameters.spr.beta.is_selected;            
        
            % Update TD Panel
            app.TDWindowSizeEditField.Value = app.parameters.td.window_size;
            app.TDFrequencyEditField.Value = app.parameters.td.frequency; 
            
            % Update PAC Panel
            app.PACWindowSizeEditField.Value = app.parameters.pac.window_size;
            
            %TODO add a converter from [0,1,0,...1] to {}
            if(app.parameters.general.egi129.is_selected)
                app.PACFrontalChannelsListBox.Value = app.parameters.pac.frontal_channels.egi129;
                app.PACParietalChannelsListBox.Value = app.parameters.pac.parietal_channels.egi129;
            elseif(app.parameters.general.dsi24.is_selected)
                app.PACFrontalChannelsListBox.Value = app.parameters.pac.frontal_channels.dsi24;
                app.PACParietalChannelsListBox.Value = app.parameters.pac.parietal_channels.dsi24;
            end    
            
            app.PACExtraLowFrequencyBandwithEditField.Value = mat2str(app.parameters.pac.extra_low_frequency.bandwith);
            app.PACHighFrequencyBandwithEditField.Value = mat2str(app.parameters.pac.high_frequency.bandwith);
            
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Settinng up String
            app.start_message = "Welcome to EEGsonic, an application by the BIAPT lab";
            app.exit_message = "Thank you for using EEGsonic! If there is an issue with the code please send a message to yacine.mahdid@mail.mcgill.ca or visit https://github.com/BIAPT/EEGsonic/issues to open an issue";
            
            % Setup
            base_directory = erase(mfilename('fullpath'),strcat('\',mfilename));
            
            disp(mfilename('fullpath'));
            % Setting up Variables
            app_data = load(strcat(base_directory,'/data/app_data'));
            app.parameters = app_data.parameters;
            app.information = app_data.information;
            app.information.base_directory = base_directory;
            
            % Welcoming the user
            clc;
            disp(app.start_message);
            
            % Populating the GUI with saved data
            update_gui(app);
            
        end

        % Menu selected function: QuitEEGsonicMenu
        function QuitEEGsonicMenuSelected(app, event)
            disp(app.exit_message);
            app.delete();
        end

        % Menu selected function: SaveSettingsMenu
        function SaveSettingsMenuSelected(app, event)
            disp('Saving Settings...');
            save_gui(app);
            parameters = app.parameters;
            information = app.information;
            save(strcat(app.information.base_directory,'/data/app_data'),'parameters','-append');
            save(strcat(app.information.base_directory,'/data/app_data'),'information','-append');
            disp('Finished Saving!');
        end

        % Button pushed function: SelectSavingDirectoryButton
        function SelectSavingDirectoryButtonPushed(app, event)
            path = uigetdir;
            if(path ~= 0)
               app.information.saving_directory = path;
               app.SavingDirectoryLabel.Text = path;
               disp(['Updated saving directory to : ',path]);
            end            
        end

        % Button pushed function: CheckRepositoryButton
        function CheckRepositoryButtonPushed(app, event)
            system('start https://github.com/BIAPT/EEGsonic')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGSonicUIFigure = uifigure;
            app.EEGSonicUIFigure.Name = 'EEG Sonic';
            app.FileMenu = uimenu(app.EEGSonicUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveSettingsMenu = uimenu(app.FileMenu);
            app.SaveSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSettingsMenuSelected, true);
            app.SaveSettingsMenu.Text = 'Save Settings';
            app.QuitEEGsonicMenu = uimenu(app.FileMenu);
            app.QuitEEGsonicMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitEEGsonicMenuSelected, true);
            app.QuitEEGsonicMenu.Text = 'Quit EEGsonic';
            app.TabGroup = uitabgroup(app.EEGSonicUIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.HomePanel = uipanel(app.HomeTab);
            app.HomePanel.Title = 'Home';
            app.TextArea = uitextarea(app.HomePanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'EEGsonic is an application by the Biosignal Interaction and Personhood Technology (BIAPT) lab at McGill. '; 'For comment or feedback please direct them to yacine.mahdid@mail.mcgill.ca or open an issue on the official github repository.'};
            app.CheckRepositoryButton = uibutton(app.HomePanel, 'push');
            app.CheckRepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @CheckRepositoryButtonPushed, true);
            app.CheckRepositoryButton.Text = 'Check Repository';
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GeneralPanel = uipanel(app.GeneralTab);
            app.GeneralPanel.Title = 'General';
            app.SelectSavingDirectoryButton = uibutton(app.GeneralPanel, 'push');
            app.SelectSavingDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSavingDirectoryButtonPushed, true);
            app.SelectSavingDirectoryButton.Text = 'Select Saving Directory';
            app.SavingDirectoryLabel = uilabel(app.GeneralPanel);
            app.SavingDirectoryLabel.Text = 'No saving directory selected';
            app.EEGdeviceButtonGroup = uibuttongroup(app.GeneralPanel);
            app.EEGdeviceButtonGroup.Title = 'EEG device';
            app.EGI129Button = uiradiobutton(app.EEGdeviceButtonGroup);
            app.EGI129Button.Text = 'EGI-129';
            app.EGI129Button.Value = true;
            app.DSI24Button = uiradiobutton(app.EEGdeviceButtonGroup);
            app.DSI24Button.Text = 'DSI-24 ';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.VisualizationPanel = uipanel(app.VisualizationTab);
            app.VisualizationPanel.Title = 'Visualization';
            app.SonificationTab = uitab(app.TabGroup);
            app.SonificationTab.Title = 'Sonification';
            app.SonificationPanel = uipanel(app.SonificationTab);
            app.SonificationPanel.Title = 'Sonification';
            app.SpectralPowerRatioTab = uitab(app.TabGroup);
            app.SpectralPowerRatioTab.Title = 'Spectral Power Ratio';
            app.SpectralPowerRatioPanel = uipanel(app.SpectralPowerRatioTab);
            app.SpectralPowerRatioPanel.Title = 'Spectral Power Ratio';
            app.WindowSizeinsecondsEditFieldLabel = uilabel(app.SpectralPowerRatioPanel);
            app.WindowSizeinsecondsEditFieldLabel.Text = 'Window Size (in seconds)';
            app.SPRWindowSizeEditField = uieditfield(app.SpectralPowerRatioPanel, 'numeric');
            app.SPRWindowSizeEditField.Limits = [0 Inf];
            app.TimeBandwithProductLabel = uilabel(app.SpectralPowerRatioPanel);
            app.TimeBandwithProductLabel.Text = 'Time Bandwith Product';
            app.SPRTimeBandwithProductEditField = uieditfield(app.SpectralPowerRatioPanel, 'numeric');
            app.SPRTimeBandwithProductEditField.Limits = [0 Inf];
            app.SPRThetaBandwithCheckBox = uicheckbox(app.SpectralPowerRatioPanel);
            app.SPRThetaBandwithCheckBox.Text = '';
            app.SPRAlphaBandwithCheckBox = uicheckbox(app.SpectralPowerRatioPanel);
            app.SPRAlphaBandwithCheckBox.Text = '';
            app.SPRBetaBandwithCheckBox = uicheckbox(app.SpectralPowerRatioPanel);
            app.SPRBetaBandwithCheckBox.Text = '';
            app.AveragingSizeinsecondsLabel = uilabel(app.SpectralPowerRatioPanel);
            app.AveragingSizeinsecondsLabel.Text = 'Averaging Size (in seconds)';
            app.SPRAveragingSizeEditField = uieditfield(app.SpectralPowerRatioPanel, 'numeric');
            app.SPRAveragingSizeEditField.Limits = [0 Inf];
            app.NumberofTapersLabel = uilabel(app.SpectralPowerRatioPanel);
            app.NumberofTapersLabel.Text = 'Number of Tapers';
            app.SPRNumberTapersEditField = uieditfield(app.SpectralPowerRatioPanel, 'numeric');
            app.SPRNumberTapersEditField.Limits = [0 Inf];
            app.ThetaBandwithinHzEditFieldLabel = uilabel(app.SpectralPowerRatioPanel);
            app.ThetaBandwithinHzEditFieldLabel.Text = 'Theta Bandwith (in Hz)';
            app.SPRThetaBandwithEditField = uieditfield(app.SpectralPowerRatioPanel, 'text');
            app.AlphaBandwithinHzLabel_2 = uilabel(app.SpectralPowerRatioPanel);
            app.AlphaBandwithinHzLabel_2.Text = 'Alpha Bandwith (in Hz)';
            app.SPRAlphaBandwithEditField = uieditfield(app.SpectralPowerRatioPanel, 'text');
            app.BetaBandwithinHzLabel_2 = uilabel(app.SpectralPowerRatioPanel);
            app.BetaBandwithinHzLabel_2.Text = 'Beta Bandwith (in Hz)';
            app.SPRBetaBandwithEditField = uieditfield(app.SpectralPowerRatioPanel, 'text');
            app.TopographicDistributionTab = uitab(app.TabGroup);
            app.TopographicDistributionTab.Title = 'Topographic Distribution';
            app.TopographicDistributionPanel = uipanel(app.TopographicDistributionTab);
            app.TopographicDistributionPanel.Title = 'Topographic Distribution';
            app.WindowSizeinsecondsEditFieldLabel_2 = uilabel(app.TopographicDistributionPanel);
            app.WindowSizeinsecondsEditFieldLabel_2.Text = 'Window Size (in seconds)';
            app.TDWindowSizeEditField = uieditfield(app.TopographicDistributionPanel, 'numeric');
            app.TDWindowSizeEditField.Limits = [0 Inf];
            app.FrequencyinHzLabel = uilabel(app.TopographicDistributionPanel);
            app.FrequencyinHzLabel.Text = 'Frequency (in Hz)';
            app.TDFrequencyEditField = uieditfield(app.TopographicDistributionPanel, 'numeric');
            app.TDFrequencyEditField.Limits = [0 50];
            app.PhaseAmplitudeCouplingTab = uitab(app.TabGroup);
            app.PhaseAmplitudeCouplingTab.Title = 'Phase Amplitude Coupling';
            app.PhaseAmplitudeCouplingPanel = uipanel(app.PhaseAmplitudeCouplingTab);
            app.PhaseAmplitudeCouplingPanel.Title = 'Phase Amplitude Coupling';
            app.WindowSizeinsecondsEditFieldLabel_3 = uilabel(app.PhaseAmplitudeCouplingPanel);
            app.WindowSizeinsecondsEditFieldLabel_3.Text = 'Window Size (in seconds)';
            app.PACWindowSizeEditField = uieditfield(app.PhaseAmplitudeCouplingPanel, 'numeric');
            app.PACWindowSizeEditField.Limits = [0 Inf];
            app.FrontalChannelsholdshiftformultiselectionLabel = uilabel(app.PhaseAmplitudeCouplingPanel);
            app.FrontalChannelsholdshiftformultiselectionLabel.Text = 'Frontal Channels (hold shift for multi-selection)';
            app.PACFrontalChannelsListBox = uilistbox(app.PhaseAmplitudeCouplingPanel);
            app.PACFrontalChannelsListBox.Items = {};
            app.PACFrontalChannelsListBox.Multiselect = 'on';
            app.PACFrontalChannelsListBox.Value = {};
            app.ParietalChannelsholdshiftformultiselectionLabel = uilabel(app.PhaseAmplitudeCouplingPanel);
            app.ParietalChannelsholdshiftformultiselectionLabel.Text = 'Parietal Channels (hold shift for multi-selection)';
            app.PACParietalChannelsListBox = uilistbox(app.PhaseAmplitudeCouplingPanel);
            app.PACParietalChannelsListBox.Items = {};
            app.PACParietalChannelsListBox.Multiselect = 'on';
            app.PACParietalChannelsListBox.Value = {};
            app.ExtraLowFrequencyinHzLabel = uilabel(app.PhaseAmplitudeCouplingPanel);
            app.ExtraLowFrequencyinHzLabel.Text = 'Extra Low Frequency (in Hz)';
            app.PACExtraLowFrequencyBandwithEditField = uieditfield(app.PhaseAmplitudeCouplingPanel, 'text');
            app.HigherFrequencyinHzLabel = uilabel(app.PhaseAmplitudeCouplingPanel);
            app.HigherFrequencyinHzLabel.Text = 'Higher Frequency (in Hz)';
            app.PACHighFrequencyBandwithEditField = uieditfield(app.PhaseAmplitudeCouplingPanel, 'text');
            app.FrontoparietalwPLITab = uitab(app.TabGroup);
            app.FrontoparietalwPLITab.Title = 'Frontoparietal wPLI';
            app.FrontoparietalwPLIPanel = uipanel(app.FrontoparietalwPLITab);
            app.FrontoparietalwPLIPanel.Title = 'Frontoparietal wPLI';
            app.WindowSizeinsecondsEditFieldLabel_4 = uilabel(app.FrontoparietalwPLIPanel);
            app.WindowSizeinsecondsEditFieldLabel_4.Text = 'Window Size (in seconds)';
            app.WPLIWindowSizeEditField = uieditfield(app.FrontoparietalwPLIPanel, 'numeric');
            app.WPLIWindowSizeEditField.Limits = [0 Inf];
            app.MidlineChannelsholdshiftformultiselectionLabel = uilabel(app.FrontoparietalwPLIPanel);
            app.MidlineChannelsholdshiftformultiselectionLabel.Text = 'Midline Channels (hold shift for multi-selection)';
            app.WPLIMidlineChannelsListBox = uilistbox(app.FrontoparietalwPLIPanel);
            app.WPLIMidlineChannelsListBox.Items = {};
            app.WPLIMidlineChannelsListBox.Multiselect = 'on';
            app.WPLIMidlineChannelsListBox.Value = {};
            app.LateralChannelsholdshiftformultiselectionLabel = uilabel(app.FrontoparietalwPLIPanel);
            app.LateralChannelsholdshiftformultiselectionLabel.Text = 'Lateral Channels (hold shift for multi-selection)';
            app.WPLILateralChannelsListBox = uilistbox(app.FrontoparietalwPLIPanel);
            app.WPLILateralChannelsListBox.Items = {};
            app.WPLILateralChannelsListBox.Multiselect = 'on';
            app.WPLILateralChannelsListBox.Value = {};
            app.NumberofSurrogatesLabel = uilabel(app.FrontoparietalwPLIPanel);
            app.NumberofSurrogatesLabel.Text = 'Number of Surrogates';
            app.WPLINumberSurrogatesEditField = uieditfield(app.FrontoparietalwPLIPanel, 'numeric');
            app.WPLINumberSurrogatesEditField.Limits = [0 Inf];
            app.pValueLabel = uilabel(app.FrontoparietalwPLIPanel);
            app.pValueLabel.Text = 'p Value';
            app.WPLIPValueEditField = uieditfield(app.FrontoparietalwPLIPanel, 'numeric');
            app.WPLIPValueEditField.Limits = [0 Inf];
            app.WPLIPValueEditField.Value = 0.5;
            app.FrontoparietaldPLITab = uitab(app.TabGroup);
            app.FrontoparietaldPLITab.Title = 'Frontoparietal dPLI';
            app.FrontoparietaldPLIPanel = uipanel(app.FrontoparietaldPLITab);
            app.FrontoparietaldPLIPanel.Title = 'Frontoparietal dPLI';
            app.HubLocationTab = uitab(app.TabGroup);
            app.HubLocationTab.Title = 'Hub Location';
            app.HubLocationPanel = uipanel(app.HubLocationTab);
            app.HubLocationPanel.Title = 'Hub Location';
            app.PermutationEntropyTab = uitab(app.TabGroup);
            app.PermutationEntropyTab.Title = 'Permutation Entropy';
            app.PermutationEntropyPanel = uipanel(app.PermutationEntropyTab);
            app.PermutationEntropyPanel.Title = 'Permutation Entropy';
            app.EEGSonicPanel = uipanel(app.EEGSonicUIFigure);
            app.EEGSonicPanel.Title = 'EEGSonic';
            app.LaunchButton = uibutton(app.EEGSonicPanel, 'push');
            app.LaunchButton.Text = 'Launch';
            app.FeedbackTextField = uitextarea(app.EEGSonicPanel);
            app.FeedbackTextField.Editable = 'off';
            app.FeedbackTextField.Value = {'Feedback Area'; ''};
            app.SpectralPowerRatioCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.SpectralPowerRatioCheckBox.Text = '';
            app.TopographicDistributionCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.TopographicDistributionCheckBox.Text = '';
            app.PhaseAmplitudeCouplingCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.PhaseAmplitudeCouplingCheckBox.Text = '';
            app.FrontoparietalwPLICheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.FrontoparietalwPLICheckBox.Text = '';
            app.FrontoparietaldPLICheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.FrontoparietaldPLICheckBox.Text = '';
            app.HubLocationCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.HubLocationCheckBox.Text = '';
            app.PermutationEntropyCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.PermutationEntropyCheckBox.Text = '';
            app.SonificationCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.SonificationCheckBox.Text = '';
            app.SonificationCheckBox.Value = true;
            app.VisualizationCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.VisualizationCheckBox.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEGsonic_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGSonicUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGSonicUIFigure)
        end
    end
end