classdef replayer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DocumentationViewer  matlab.ui.Figure
        Menu                 matlab.ui.container.Menu
        TabGroup             matlab.ui.container.TabGroup
        ReplayerTab          matlab.ui.container.Tab
        DataSliderLabel      matlab.ui.control.Label
        DataSlider           matlab.ui.control.Slider
        StartSliderLabel     matlab.ui.control.Label
        StartSlider          matlab.ui.control.Slider
        StopSliderLabel      matlab.ui.control.Label
        StopSlider           matlab.ui.control.Slider
        InformationTab       matlab.ui.container.Tab
        PlayButton           matlab.ui.control.StateButton
    end


    properties (Access = public)
        main_application; % link to EEGsonic_app
    end

    properties (Access = private)
        % Structure containing paths
    end

    methods (Access = private)
        %
    end

    methods (Access = public)
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.doc = struct();
            % Setup Base Directory
            base_directory = erase(mfilename('fullpath'),strcat(filesep,mfilename));
            
            % Setup Doc Directory
            app.doc.directory = strcat(base_directory,strcat(filesep,'..',filesep,'..',filesep,'doc'))
     
        end

        % Close request function: DocumentationViewer
        function DocumentationViewerCloseRequest(app, event)
            app.main_application.is_replayer_running = 0;
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DocumentationViewer = uifigure;
            app.DocumentationViewer.Name = 'Documentation Viewer';
            app.DocumentationViewer.CloseRequestFcn = createCallbackFcn(app, @DocumentationViewerCloseRequest, true);
            app.Menu = uimenu(app.DocumentationViewer);
            app.Menu.Text = 'Menu';
            app.TabGroup = uitabgroup(app.DocumentationViewer);
            app.ReplayerTab = uitab(app.TabGroup);
            app.ReplayerTab.Title = 'Replayer';
            app.DataSliderLabel = uilabel(app.ReplayerTab);
            app.DataSliderLabel.Text = 'Data';
            app.DataSlider = uislider(app.ReplayerTab);
            app.StartSliderLabel = uilabel(app.ReplayerTab);
            app.StartSliderLabel.Text = 'Start';
            app.StartSlider = uislider(app.ReplayerTab);
            app.StopSliderLabel = uilabel(app.ReplayerTab);
            app.StopSliderLabel.Text = 'Stop';
            app.StopSlider = uislider(app.ReplayerTab);
            app.StopSlider.Value = 100;
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.PlayButton = uibutton(app.DocumentationViewer, 'state');
            app.PlayButton.Text = 'Play';
        end
    end

    methods (Access = public)

        % Construct app
        function app = replayer_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DocumentationViewer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DocumentationViewer)
        end
    end
end